classdef SAR_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        EditFieldLabel       matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        EditField_2Label     matlab.ui.control.Label
        EditField_2          matlab.ui.control.NumericEditField
        EditField_3Label     matlab.ui.control.Label
        EditField_3          matlab.ui.control.NumericEditField
        EditField_4Label     matlab.ui.control.Label
        EditField_4          matlab.ui.control.NumericEditField
        EditField_5Label     matlab.ui.control.Label
        EditField_5          matlab.ui.control.NumericEditField
        EditField_6Label     matlab.ui.control.Label
        EditField_6          matlab.ui.control.NumericEditField
        EditField_7Label     matlab.ui.control.Label
        EditField_7          matlab.ui.control.NumericEditField
        EditField_8Label     matlab.ui.control.Label
        EditField_8          matlab.ui.control.NumericEditField
        EditField_9Label     matlab.ui.control.Label
        EditField_9          matlab.ui.control.NumericEditField
        EditField_10Label    matlab.ui.control.Label
        EditField_10         matlab.ui.control.NumericEditField
        EditField_11Label    matlab.ui.control.Label
        EditField_11         matlab.ui.control.NumericEditField
        EditField_3Label_2   matlab.ui.control.Label
        EditField_4Label_2   matlab.ui.control.Label
        EditField_5Label_2   matlab.ui.control.Label
        EditField_6Label_2   matlab.ui.control.Label
        EditField_7Label_2   matlab.ui.control.Label
        EditField_8Label_2   matlab.ui.control.Label
        EditField_9Label_2   matlab.ui.control.Label
        EditField_10Label_2  matlab.ui.control.Label
        EditField_11Label_2  matlab.ui.control.Label
        Label                matlab.ui.control.Label
        EditField_12         matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        EditField_13         matlab.ui.control.NumericEditField
        Label_3              matlab.ui.control.Label
        EditField_14         matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        EditField_15         matlab.ui.control.NumericEditField
        Label_5              matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        Button               matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Lamp                 matlab.ui.control.Lamp
        Button_3             matlab.ui.control.Button
        Lamp_2               matlab.ui.control.Lamp
        Lamp_3               matlab.ui.control.Lamp
        Label_7              matlab.ui.control.Label
        EditField_16         matlab.ui.control.NumericEditField
        EditField_11Label_3  matlab.ui.control.Label
        hzLabel              matlab.ui.control.Label
        mLabel               matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        sLabel               matlab.ui.control.Label
        Button_4             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Na;% 方位向维度
        Nr; % 距离向维度
        c; % 光速
        D; % 天线长度
        f0 ;% 雷达工作频率
        Fs;  % 距离向采样率
        Br; % 距离向带宽
        Tp ;  % 发射脉冲时宽
        PRF; % 重复脉冲频率
        Vr; % 雷达有效速度
        R0  ;% 最近斜率
        fdc; % 多普勒中心频率
        Kr; % 距离调频率
        lambda; % 波长
        theta; % 斜视角
        dyl;%延迟时间
        Ba; % 方位向带宽
        
        StateButton_1 = 0; % 第一按钮按下的状态,0为未按下的状态
        StateButton_2 = 0; % 第二按钮按下的状态
        StateButton_3 = 0; % 第三按钮按下的状态
        
        data; % 卫星数据
        image;% 成像之后的数据
    end


    methods (Access = private)
        
        function [sig] = CSA_Imaging(~,sig,para)
            c 		= para.c;
            Ba 		= para.Ba;
            Br 		= para.B;
            f0 		= para.Fc;
            Kr 		= para.Kr;	
            Fa  	= para.prf;	
            Fr 		= para.Fs;	
            fdc 	= para.Fdc;
            Vr 		= para.Vr;
            R0 		= para.Rs;
            
            lambda = c/f0;
            [Nr,Na]= size(sig);
            
            sub_Nr  = round(Br/Fr*Nr/2)*2;%求有效成像数据宽度
            win1    = zeros(Nr,1);
            win1(end/2-sub_Nr/2+1:end/2+sub_Nr/2) = kaiser(sub_Nr,2.500);
            
            sub_Na = round(Ba/Fa*Na/2)*2;
            win2   = zeros(1,Na);
            win2(end/2-sub_Na/2+1:end/2+sub_Na/2)=kaiser(sub_Na,2.500).';%方位向加窗
            %%
            squint_theta = asin(fdc*lambda/2/Vr);%利用多普勒中心频率求斜视角4.33
            dly0 = 2*R0/cos(squint_theta)/c;   
            t_a  = ((-Na/2:Na/2-1)/Fa);
            t_r  = ((-Nr/2:Nr/2-1)/Fr+dly0);         
            f_r  = (-Nr/2:Nr/2-1)/Nr*Fr;
            f_a  = (-Na/2:Na/2-1)/Na*Fa + fdc;
            f_r  = f_r.'*ones(1,Na);
            f_a  = ones(Nr,1)*f_a;
            %%
            %值得注意的是这边的行是方位向，列是距离向
            %%%%%%%%%%% FDC_compensition %%%%%%%%%%%%%%%%
            ph_fdc = exp(-1i*2*pi*fdc*t_a);
            sig    = sig.*(ones(Nr,1)*ph_fdc);
            %%%%%%%%%%%%%%%% a_fft %%%%%%%%%%%%%%%%%%%%%%
            sig = fftshift(fft(fftshift(sig,2),[],2),2);%进入距离多普勒域
            % sig =fft(sig,[],2);
            sig = sig.*(ones(Nr,1)*win2);
            %%%%%%%%%%%%%% chirp scaling %%%%%%%%%%%%%%%%
            R_ref=R0;%距离多普勒域变标方程
            R0   = (t_r.'*c/2)*ones(1,Na)*cos(squint_theta);
            D    = sqrt(1-(c*f_a/(2*Vr*f0)).^2);
            Dref = sqrt(1-(c*fdc/(2*Vr*f0)).^2);
            tao  = (2/c)*(R0/Dref+R_ref*(1./D-1/Dref));                                                  
            tao2 = tao-2*R_ref./(c*D);
            Z    = c*(f_a.^2).*R0./(2*Vr^2*f0^3*D.^3);%k_src
            Km   = Kr./(1-Kr*Z);
            
            alpha=Dref./D-1;%变标方程中参数  p191 7.30
            Ssc  =exp(1i*pi*Km.*alpha.*(tao2.^2));
            sig  =sig.*Ssc;
            clear alpha Ssc Z  tao2 tao
            %%%%%%%%%%%%%%%%%%%%% range fft %%%%%%%%%%%%%%%%
            sig = fftshift(fft(fftshift(sig,1),[],1),1);%方位向傅里叶变化进入二维频域
            sig = sig.*(win1*ones(1,Na));
            %%%%%%%%%%%%%%% range MF and RCMC %%%%%%%%%%%%%%
            ph1=1i*pi*D.*f_r.^2./(Km*Dref);
            ph2=1i*4*pi*R_ref*f_r.*(1./D-1./Dref)/c;
            sig=sig.*exp(ph1+ph2);
            
            clear ph1 ph2
            %%%%%%%%%%%%%%%%%%%%% range ifft %%%%%%%%%%%%%%%
            sig = fftshift(ifft(fftshift(sig,1),[],1),1);%距离向傅里叶逆变化
            % %%%%%%%%%%%%%%%%%%%%% azimuth MF %%%%%%%%%%%%%
            
            ph3 = 1i*4*pi*R0*f0.*D/c;%方位向匹配滤波
            ph4 = -1i*(4*pi*Km/c^2).*(1-D/Dref).*((R0./D-R_ref./D).^2);%附加相位校正
            sig =  sig.*exp(ph3+ph4);
            
            clear ph3 ph4 D sref_ka
            %%%%%%%%%%%%%%%%%% azimuth_ifft %%%%%%%%%%%%%%%%
            sig = fftshift(ifft(fftshift(sig,2),[],2),2);
        end
        
        function [sig] =RDA_Imaging(~,sig,para)
            %RDA_Imaging_02
            %距离向加窗，
            %应该是成功的
            c 		= para.c;
            Ba 		= para.Ba;
            Br 		= para.B;
            f0 		= para.Fc;
            Kr 		= para.Kr;	
            Fa  	= para.prf;	
            Fr 		= para.Fs;	
            fdc 	= para.Fdc;
            Vr 		= para.Vr;
            R0 		= para.Rs;
            Tp      = para.Tp;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            lambda = c/f0;
            [Nr,Na]= size(sig);
            % Nr_valid = Tp*Fr;
            sub_Nr  = round(Br/Fr*Nr/2)*2;%求有效成像数据宽度
            win1    = zeros(Nr ,1);
            win1(end/2-sub_Nr/2+1:end/2+sub_Nr/2) = kaiser(sub_Nr,2.500);
            
            sub_Na = round(Ba/Fa*Na/2)*2;
            win2   = zeros(1,Na);
            win2(end/2-sub_Na/2+1:end/2+sub_Na/2)=kaiser(sub_Na,2.500).';%方位向加窗
            
            
            %%
            %dim 1是列方向 2是行方向
            %距离向补零
            Zr = ceil(Tp*Fr);%距离向补零
            % sig = cat(1,sig,zeros(Zr,Na));
            %%
            %设置时间轴
            squint_theta = asin(fdc*lambda/2/Vr);%利用多普勒中心频率求斜视角4.33
            dly0 = 2*R0/cos(squint_theta)/c;%延迟时间   
            t_a  = ((-Na/2:Na/2-1)/Fa);
            t_r  = ((-Nr/2:Nr/2-1)/Fr+dly0);     
            f_r  = (-Nr/2:Nr/2-1)/Nr*Fr;
            f_a  = (-Na/2:Na/2-1)/Na*Fa + fdc;
            f_r  = f_r.'*ones(1,Na);
            %%
            %距离压缩
            S_ref = exp(1i*pi*(f_r.^2)/Kr);
            S_range = fftshift(fft(fftshift(sig,1),Nr,1),1);     % 进行距离向傅里叶变换，零频在两端。
            S_range_c = S_range.*S_ref;  %乘上频域匹配滤波器
            S_range_c = S_range_c.*(win1*ones(1,Na));
            s_rc = fftshift(ifft(fftshift(S_range_c,1),[],1),1);            % 完成距离压缩，回到二维时域。
            N_rg = Nr;                        % 完全卷积的长度
            s_rc_c = s_rc(1:N_rg,:);                % 取前 N_rg列。
            clear S_range_c  S_range S_ref s_rc
            %%
            %值得注意的是这边的行是方位向，列是距离向
            %1是列方向，2是行方向
            %%%%%%%%%%%%距离压缩%%%%%%%%%%%%%%
            %%%%%%%%%%% FDC_compensition %%%%%%%%%%%%%%%%
            ph_fdc = exp(-1i*2*pi*fdc*t_a);
            s_rc_c   = s_rc_c.*(ones(N_rg ,1)*ph_fdc);
            %%%%%%%%%%%%%%%% a_fft %%%%%%%%%%%%%%%%%%%%%%
            S_rd= fftshift(fft(fftshift(s_rc_c,2),[],2),2);%进入距离多普勒域
            clear s_rc_c
            % S_rd = S_rd.*(ones(N_rg,1)*win2);%方位向加窗
            %%
            tr_RCMC = ( -N_rg/2 : (N_rg/2-1) )/Fr+dly0;   % 在新的距离线长度下的时间轴。
            R0_RCMC =((c/2).*tr_RCMC*cos(squint_theta )).';       % 随距离线变化的R0，记为R0_RCMC，用来计算RCM和Ka。
            delta_Rrd_fn = (R0_RCMC)/(8*Vr^2)*(lambda^2.*((f_a).^2));%求出需要校正的距离徙动
            num_range = c/(2*Fr);   % 一个距离采样单元，对应的长度
            delta_Rrd_fn_num = delta_Rrd_fn./num_range; % 每一个方位向频率，其RCM对应的距离采样单元数
            R = 8;  % sinc插值核长度
            S_rd_rcmc = zeros(N_rg,Na); % 用来存放RCMC后的值
            h = waitbar(0,'Sinc插值');
            for p = 1 : Na
                for q = 1 : N_rg     % 此时距离向的长度是 (Nrg-Nr+1)=N_rg        
                    delta_Rrd_fn_p = delta_Rrd_fn_num(q,p);%将每一个方位向频率对于的RCM对应的距离采样单元数拿出
                    Rrd_fn_p = q + delta_Rrd_fn_p;%同一个方位向频率对应的距离采样点加上RCM对应的采样单元数
                    Rrd_fn_p_zheng = ceil(Rrd_fn_p);        % ceil，向上取整。
                    %与下式效果相同，更好理解。一个数减去更小的数为更大所以下式中间要-1
                     ii=Rrd_fn_p-((Rrd_fn_p_zheng-R/2):1:(Rrd_fn_p_zheng+R/2-1));
                    rcmc_sinc = sinc(ii);
                    rcmc_sinc = (rcmc_sinc/sum(rcmc_sinc)).';   % 插值核的归一化
                    % 由于S_rd只有整数点取值，且范围有限。因此插值中要考虑它的取值溢出边界问题。
                    % 这里我采取循环移位的思想，用来解决取值溢出问题。
                    if (Rrd_fn_p_zheng-R/2) > N_rg    % 全右溢
                        ll = (Rrd_fn_p_zheng-R/2-N_rg:1:Rrd_fn_p_zheng+R/2-1-N_rg).';
                    else
                        if (Rrd_fn_p_zheng+R/2-1) > N_rg    % 部分右溢
                            ll_1 = (Rrd_fn_p_zheng-R/2:1:N_rg);
                            ll_2 = (1:1:Rrd_fn_p_zheng+R/2-1-N_rg);
                            ll = [ll_1,ll_2].';
                        else
                            if (Rrd_fn_p_zheng+R/2-1) < 1    % 全左溢（不可能发生，但还是要考虑）
                                ll = (Rrd_fn_p_zheng-R/2+N_rg:1:Rrd_fn_p_zheng+R/2-1+N_rg).';
                            else
                                if (Rrd_fn_p_zheng-R/2) < 1       % 部分左溢
                                    ll_1 = (Rrd_fn_p_zheng-R/2+N_rg:1:N_rg);
                                    ll_2 = (1:1:Rrd_fn_p_zheng+R/2-1);
                                    ll = [ll_1,ll_2].';
                                else
                                    ll = (Rrd_fn_p_zheng-R/2:1:Rrd_fn_p_zheng+R/2-1).';
                                end                    
                            end
                        end
                    end   
                    rcmc_S_rd = S_rd(ll,p);
                    S_rd_rcmc(q,p) = sum( rcmc_sinc.*rcmc_S_rd );  
                end
                waitbar(p/Na);
            end
            close(h);
            %%方位压缩
            S_rd_rcmc = S_rd_rcmc.*(ones(N_rg,1)*win2);%方位向加窗
            fa_azimuth_MF = f_a;         % 方位频率轴，采用和RCMC中所用的频率轴相同。
            Ka = 2*Vr^2*(cos(squint_theta))^3./(lambda.* R0_RCMC.*ones(1,Na));  	% 方位向调频率，是随最近斜距R0变化的。书本P86
            Ka_1 = 1./Ka;     
            Haz = exp( -1j*pi.*((fa_azimuth_MF).^2.*Ka_1) );	% 方位向匹配滤波器
            %%
            S_rd_c = S_rd_rcmc.*Haz;            % 乘以匹配滤波器
            
            sig = fftshift(ifft(fftshift(S_rd_c,2),[],2),2);       	% 完成方位压缩，变到图像域。结束。
            clear S_rd_rcmc  S_rd_c
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if (app.StateButton_1==0&&app.StateButton_1==0&&app.StateButton_1==0)
                app.Na = app.EditField.Value;
                app.Nr = app.EditField_2.Value;
                app.c = app.EditField_3.Value;
                app.D = app.EditField_4.Value;
                app.f0 = app.EditField_16.Value;
                app.Fs = app.EditField_5.Value;
                app.Br = app.EditField_6.Value;
                app.Tp = app.EditField_7.Value;
                app.PRF = app.EditField_8.Value/2;
                app.Vr = app.EditField_9.Value;
                app.R0 = app.EditField_10.Value;
                app.fdc = app.EditField_11.Value;
                %计算
                app.Ba = 2*app.Vr/app.D;%计算方位向带宽
                app.EditField_12.Value = app.Ba;%赋值显示
                app.Kr = -1*app.Br/app.Tp;%计算距离调频率
                app.lambda = app.c/app.f0;%计算波长
                app.EditField_13.Value = app.lambda;%赋值显示波长
                app.theta = asin(app.fdc*app.lambda/2/app.Vr);%利用多普勒中心求斜视角，弧度
                app.EditField_14.Value = app.theta*180/pi;%赋值显示斜视角，转为角度
                app.dyl = 2*app.R0/cos(app.theta)/app.c;%计算延迟时间     
                app.EditField_15.Value = app.dyl; %将延迟时间赋值显示
                app.Lamp_2.Color = [0.00,1.00,0.00];%信号灯变为允许执行
                app.StateButton_1 = 1; % 第一按钮按下的状态
            else
                h=warndlg('不满足点击条件，请将所有信号灯变红后尝试!','提示','modal'); 
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.StateButton_1 = 0; % 第一按钮按下的状态,0为未按下的状态
            app.StateButton_2 = 0; % 第二按钮按下的状态
            app.StateButton_3 = 0; % 第三按钮按下的状态
            app.Lamp.Color   = [1.00,0.00,0.00];%信号灯变为红灯
            app.Lamp_2.Color = [1.00,0.00,0.00];%信号灯变为红灯
            app.Lamp_3.Color = [1.00,0.00,0.00];%信号灯变为红灯
            %还原参数
            app.Ba = 0;
            app.EditField_12.Value = 0;
            app.Kr = 0;
            app.lambda = 0;
            app.EditField_13.Value = 0;
            app.theta = 0;
            app.EditField_14.Value = 0;
            app.dyl = 0;
            app.EditField_15.Value = 0;
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if(app.StateButton_1==1)
                 % Display uigetfile dialog
                filterspec = {'*.caw','All Files'};
                [FileName,PathName] = uigetfile(filterspec);
                
                % Make sure user didn't cancel uigetfile dialog
                if (ischar(PathName))
                   fname = [PathName FileName];
                   fid   = fopen(fname,'rb');%读取二进制文件
                   app.data  = fread(fid,[2*app.Na,app.Nr],'float32');
                   app.data  = app.data(1:2:end,:).' + 1i*app.data(2:2:end,:).';
                   fclose(fid);
                   app.Lamp_3.Color = [0.00,1.00,0.00];%信号灯变为绿
                   
                end
            else
                h=warndlg('不满足点击条件，请将确认参数信号灯变绿后尝试!','提示','modal'); 
            end
            app.StateButton_2 = 1; % 第一按钮按下的状态
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.StateButton_2==1)
                para.c       = app.c;
                para.prf     = app.PRF;
                para.Vr      = app.Vr;
                para.Rs      = app.R0;
                para.R0      = app.R0;
                para.Tp      = app.Tp;
                para.Kr      = app.Kr;
                para.Fc      = app.f0;
                para.Fs      = app.Fs;
                para.B       = app.Br;
                para.D       = app.D;
                para.Fdc     = app.fdc;
                para.lambda  = app.lambda;
                para.Ba 	 = app.Ba;%方位向带宽，多普勒带宽
                if(app.DropDown.Value=="Chirp-Scaling")
                    app.image =CSA_Imaging(app,app.data,para);
                    app.Lamp.Color = [0.00,1.00,0.00];%信号灯变为绿
                elseif(app.DropDown.Value=="Range-Doppler")
                    app.image =RDA_Imaging(app,app.data,para);
                    app.Lamp.Color = [0.00,1.00,0.00];%信号灯变为绿
                end
            else
                 h=warndlg('不满足点击条件，请将载入雷达参数信号灯变绿后尝试!','提示','modal'); 
            end
            app.StateButton_3=1;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if(app.StateButton_3==1)
                %% 对数变换
                J=abs(app.image);
%                 v=100;
                r=mat2gray(double(J));
%                 S=log(1+v*r)/(log(v+1));
                S=imadjust(r);
                figure;
                imshow(S,[]);
            else
                 h=warndlg('不满足点击条件，请将显示图像信号灯变绿后尝试!','提示','modal'); 
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'SAR-GUI_03.jpg';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '方位向维度';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 16384;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '距离向维度';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 8192;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '光速';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 299792458;
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '天线长度';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 15;
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '距离向采样率';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 66667000;
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '距离向带宽';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 40000000;
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '发射脉冲时宽';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Value = 3e-05;
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '方位向采样率';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Value = 2727.768685;
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '平台有效速度';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Value = 7123.93052;
            app.EditField_10Label = uilabel(app.UIFigure);
            app.EditField_10Label.Text = '景中心斜距';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Value = 961241.045189;
            app.EditField_11Label = uilabel(app.UIFigure);
            app.EditField_11Label.Text = '多普勒中心频率';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.Value = 14.251278;
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = 'm/s';
            app.EditField_4Label_2 = uilabel(app.UIFigure);
            app.EditField_4Label_2.Text = 'm';
            app.EditField_5Label_2 = uilabel(app.UIFigure);
            app.EditField_5Label_2.Text = 'hz';
            app.EditField_6Label_2 = uilabel(app.UIFigure);
            app.EditField_6Label_2.Text = 'hz';
            app.EditField_7Label_2 = uilabel(app.UIFigure);
            app.EditField_7Label_2.Text = 's';
            app.EditField_8Label_2 = uilabel(app.UIFigure);
            app.EditField_8Label_2.Text = 'hz';
            app.EditField_9Label_2 = uilabel(app.UIFigure);
            app.EditField_9Label_2.Text = 'm/s';
            app.EditField_10Label_2 = uilabel(app.UIFigure);
            app.EditField_10Label_2.Text = 'm';
            app.EditField_11Label_2 = uilabel(app.UIFigure);
            app.EditField_11Label_2.Text = 'hz';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '多普勒带宽';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '波长';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '斜视角';
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '延迟时间';
            app.EditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_15.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '成像算法';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Chirp-Scaling', 'Range-Doppler'};
            app.DropDown.Value = 'Chirp-Scaling';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始成像';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '确认参数';
            app.Lamp = uilamp(app.UIFigure);
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '载入卫星数据';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '雷达工作频率';
            app.EditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_16.Value = 5400000000;
            app.EditField_11Label_3 = uilabel(app.UIFigure);
            app.EditField_11Label_3.Text = 'hz';
            app.hzLabel = uilabel(app.UIFigure);
            app.hzLabel.Text = 'hz';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '°';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '终止成像并还原状态';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '显示图像';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAR_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end